classdef ProyectoFinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        TemperaturaSlider         matlab.ui.control.Slider
        TabGroup                  matlab.ui.container.TabGroup
        InterpolacinTab           matlab.ui.container.Tab
        GridLayout2               matlab.ui.container.GridLayout
        CheckLineal               matlab.ui.control.CheckBox
        CheckSpline               matlab.ui.control.CheckBox
        CheckPolinomica           matlab.ui.control.CheckBox
        UIAxes                    matlab.ui.control.UIAxes
        ErrorPorcentualTab        matlab.ui.container.Tab
        GridLayout2_2             matlab.ui.container.GridLayout
        CheckLineal_2             matlab.ui.control.CheckBox
        CheckSpline_2             matlab.ui.control.CheckBox
        CheckPolinomica_2         matlab.ui.control.CheckBox
        UIAxes_2                  matlab.ui.control.UIAxes
        TemperaturaSpinner        matlab.ui.control.Spinner
        ResultadoSplineLabel      matlab.ui.control.Label
        ResultadoLinealLabeL      matlab.ui.control.Label
        ResultadoPolinomicoLabel  matlab.ui.control.Label
    end

    
    methods (Access = private)

        function plotInterpolacion(app, T_obj)
            %=======================
            % 1. Datos base
            %=======================
            T = [15 20 25 30 35 40]; % Temperaturas en °C
            eta = [20.5 20.2 19.8 19.4 19.0 18.6]; % Eficiencias en %
        
            %=======================
            % 2. Validación de entrada
            %=======================
            epsilon = 1e-6;
            if ~isscalar(T_obj) || T_obj < min(T) - epsilon || T_obj > max(T) + epsilon
                uialert(app.UIFigure, 'Temperatura fuera del rango [15, 40]°C', 'Error de entrada');
                return;
            end
            %=======================
            % 3. Interpolaciones
            %=======================
            n = length(T) - 1;
            p = polyfit(T, eta, n);         % Polinomio de grado 5
            eta_poly = interpolacionPolinomica(app, T, eta, T_obj);
            eta_spline = interpolacionSplineNatural(app, T, eta, T_obj);
            eta_linear = interpolacionLineal(app, T, eta, T_obj);
                
            %=======================
            % 4. Curvas completas para graficar
            %=======================
            T_dense = linspace(min(T), max(T), 200);
            eta_poly_curve   = arrayfun(@(x) interpolacionPolinomica(app, T, eta, x), T_dense);
            eta_spline_curve = arrayfun(@(x) interpolacionSplineNatural(app, T, eta, x), T_dense);
            eta_linear_curve = arrayfun(@(x) interpolacionLineal(app, T, eta, x), T_dense);
        
            %=======================
            % 5. Gráfica de interpolaciones
            %=======================
            cla(app.UIAxes);
            
            hold(app.UIAxes, 'on');
            
            % Puntos originales
            plot(app.UIAxes, T, eta, 'ro', 'MarkerSize', 8, 'DisplayName', 'Datos');
            
            % Curvas (según checkboxes)
            if app.CheckPolinomica.Value
                plot(app.UIAxes, T_dense, eta_poly_curve, 'b-', 'DisplayName', 'Polinómica');
                plot(app.UIAxes, T_obj, eta_poly, 'bs', 'MarkerSize', 10, ...
                    'MarkerFaceColor', 'b', 'DisplayName', 'Est. Polinómica');
            end
            
            if app.CheckSpline.Value
                plot(app.UIAxes, T_dense, eta_spline_curve, 'g--', 'DisplayName', 'Spline');
                plot(app.UIAxes, T_obj, eta_spline, 'g^', 'MarkerSize', 10, ...
                    'MarkerFaceColor', 'g', 'DisplayName', 'Est. Spline');
            end
            
            if app.CheckLineal.Value
                plot(app.UIAxes, T_dense, eta_linear_curve, 'm-.', 'DisplayName', 'Lineal');
                plot(app.UIAxes, T_obj, eta_linear, 'md', 'MarkerSize', 10, ...
                    'MarkerFaceColor', 'm', 'DisplayName', 'Est. Lineal');
            end

            if any([app.CheckPolinomica.Value, app.CheckSpline.Value, app.CheckLineal.Value])
                legend(app.UIAxes, 'Location', 'southwest');
            end
            %=======================
            % 6. Cálculo y gráfica de errores
            %=======================
            % Interpolación de referencia para las curvas
            
            % Referencia: polinomio de grado 5 usando todos los puntos
            p_ref = polyfit(T, eta, length(T) - 1);
            eta_real = polyval(p_ref, T_dense);         % valores de referencia para la curva
            eta_interp_ref = polyval(p_ref, T_obj);     % valor de referencia en T_obj
            
            % Seguridad ante división por cero
            eta_real(eta_real == 0) = eps;
            if eta_interp_ref == 0
                eta_interp_ref = eps;
            end
            
            % Errores porcentuales en curvas
            error_poly   = abs((eta_poly_curve   - eta_real) ./ eta_real) * 100;
            error_spline = abs((eta_spline_curve - eta_real) ./ eta_real) * 100;
            error_lineal = abs((eta_linear_curve - eta_real) ./ eta_real) * 100;
            
            % Errores porcentuales en el punto actual
            error_poly_pt   = abs((eta_poly   - eta_interp_ref) / eta_interp_ref) * 100;
            error_spline_pt = abs((eta_spline - eta_interp_ref) / eta_interp_ref) * 100;
            error_lineal_pt = abs((eta_linear - eta_interp_ref) / eta_interp_ref) * 100;
            
            % === Graficar errores en UIAxes_2 ===
            cla(app.UIAxes_2);
            xlim(app.UIAxes_2, [13 42]);
            app.UIAxes_2.XTick = [15 20 25 30 35 40];
            hold(app.UIAxes_2, 'on');
            
            if app.CheckPolinomica_2.Value
                plot(app.UIAxes_2, T_dense, error_poly, 'b-', 'DisplayName', 'Error Polinómica');
                plot(app.UIAxes_2, T_obj, error_poly_pt, 'bo', 'MarkerFaceColor', 'b', ...
                    'DisplayName', 'Error punto Polinómica');
            end
            
            if app.CheckSpline_2.Value
                plot(app.UIAxes_2, T_dense, error_spline, 'g--', 'DisplayName', 'Error Spline');
                plot(app.UIAxes_2, T_obj, error_spline_pt, 'g^', 'MarkerFaceColor', 'g', ...
                    'DisplayName', 'Error punto Spline');
            end
            
            if app.CheckLineal_2.Value
                plot(app.UIAxes_2, T_dense, error_lineal, 'm-.', 'DisplayName', 'Error Lineal');
                plot(app.UIAxes_2, T_obj, error_lineal_pt, 'ms', 'MarkerFaceColor', 'm', ...
                    'DisplayName', 'Error punto Lineal');
            end
            
            % Estética
            xlabel(app.UIAxes_2, 'Temperatura (°C)');
            ylabel(app.UIAxes_2, 'Error porcentual (%)');
            title(app.UIAxes_2, 'Error de Interpolación vs datos');
            if any([app.CheckPolinomica_2.Value, app.CheckSpline_2.Value, app.CheckLineal_2.Value])
                legend(app.UIAxes_2, 'Location', 'northeast');
            end
            ylim(app.UIAxes_2, [-0.1 0.7]); 
            grid(app.UIAxes_2, 'on');
            hold(app.UIAxes_2, 'off');

            %=======================
            % 7. Etiquetas
            %=======================

            app.ResultadoPolinomicoLabel.Text = ...
                sprintf('Polinómica: %.3f%%\nError: %.3f%%', eta_poly, error_poly_pt);
            
            app.ResultadoSplineLabel.Text = ...
                sprintf('Spline: %.3f%%\nError: %.3f%%', eta_spline, error_spline_pt);
            
            app.ResultadoLinealLabeL.Text = ...
                sprintf('Lineal: %.3f%%\nError: %.3f%%', eta_linear, error_lineal_pt);
        end


        function y_interp = interpolacionPolinomica(app, T, eta, x)
            n = length(T);
            coef = eta;
            % Construcción de diferencias divididas
            for j = 2:n
                for i = n:-1:j
                    coef(i) = (coef(i) - coef(i-1)) / (T(i) - T(i-j+1));
                end
            end
            % Evaluación del polinomio de Newton
            y_interp = coef(n);
            for k = n-1:-1:1
                y_interp = y_interp * (x - T(k)) + coef(k);
            end 
        end
        
        function y_interp = interpolacionSplineNatural(app, T, eta, x)
            n = length(T);
            h = diff(T);
            b = diff(eta) ./ h;
        
            % Sistema tridiagonal
            A = zeros(n);
            rhs = zeros(n,1);
            A(1,1) = 1; A(n,n) = 1;
        
            for i = 2:n-1
                A(i,i-1) = h(i-1);
                A(i,i)   = 2 * (h(i-1) + h(i));
                A(i,i+1) = h(i);
                rhs(i) = 3 * (b(i) - b(i-1));
            end
        
            c = A \ rhs;
        
            % Coeficientes spline
            a = eta(1:n-1);
            d = diff(c) ./ (3*h);
            b = b - h .* (2*c(1:n-1) + c(2:n)) / 3;
        
            % Buscar intervalo donde cae x
            i = find(x >= T(1:end-1) & x <= T(2:end), 1);
            dx = x - T(i);
        
            y_interp = a(i) + b(i)*dx + c(i)*dx^2 + d(i)*dx^3;
        end
        
        function y_interp = interpolacionLineal(app, T, eta, x)
            for i = 1:length(T)-1
                if x >= T(i) && x <= T(i+1)
                    t1 = T(i); t2 = T(i+1);
                    e1 = eta(i); e2 = eta(i+1);
                    y_interp = e1 + (e2 - e1) * (x - t1) / (t2 - t1);
                    return;
                end
            end
            y_interp = NaN; % fuera de rango
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TemperaturaSpinner.Value = 15;
            plotInterpolacion(app, 15);
        end

        % Value changed function: TemperaturaSlider
        function TemperaturaSliderValueChanged(app, event)
            rawValue = app.TemperaturaSlider.Value;

            % Redondear a 1 decimal
            roundedValue = round(rawValue, 1);
        
            % Cambiar slider a un decimal
            app.TemperaturaSlider.Value = roundedValue;
        
            % Mantener spinner en sync e interpolar
            app.TemperaturaSpinner.Value = roundedValue;
            plotInterpolacion(app, roundedValue);
        end

        % Value changed function: TemperaturaSpinner
        function TemperaturaSpinnerValueChanged(app, event)
            typedValue = round(app.TemperaturaSpinner.Value, 1);  % Redondear a 1 decimal

            % Limitar el valor al rango permitido [15, 40]
            if typedValue < 15
                typedValue = 15;
                uialert(app.UIFigure, 'Temperatura fuera del rango [15, 40]', 'Valor inválido');
            elseif typedValue > 40
                typedValue = 40;
                uialert(app.UIFigure, 'Temperatura fuera del rango [15, 40]', 'Valor inválido');
            end
        
            % Aplicar el valor corregido
            app.TemperaturaSpinner.Value = typedValue;
            app.TemperaturaSlider.Value = typedValue;
        
            % Interpolación actualizada
            plotInterpolacion(app, typedValue);
        end

        % Value changed function: CheckPolinomica
        function CheckPolinomicaValueChanged(app, event)
            plotInterpolacion(app, app.TemperaturaSpinner.Value);
        end

        % Value changed function: CheckSpline
        function CheckSplineValueChanged(app, event)
            plotInterpolacion(app, app.TemperaturaSpinner.Value);
        end

        % Value changed function: CheckLineal
        function CheckLinealValueChanged(app, event)
            plotInterpolacion(app, app.TemperaturaSpinner.Value);
        end

        % Value changed function: CheckPolinomica_2
        function CheckPolinomica_2ValueChanged(app, event)
            plotInterpolacion(app, app.TemperaturaSpinner.Value);
        end

        % Value changed function: CheckSpline_2
        function CheckSpline_2ValueChanged(app, event)
            plotInterpolacion(app, app.TemperaturaSpinner.Value);
        end

        % Value changed function: CheckLineal_2
        function CheckLineal_2ValueChanged(app, event)
            plotInterpolacion(app, app.TemperaturaSpinner.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ResultadoPolinomicoLabel = uilabel(app.GridLayout);
            app.ResultadoPolinomicoLabel.Text = '';
            app.ResultadoLinealLabeL = uilabel(app.GridLayout);
            app.ResultadoLinealLabeL.Text = '';
            app.ResultadoSplineLabel = uilabel(app.GridLayout);
            app.ResultadoSplineLabel.Text = '';
            app.TemperaturaSpinner = uispinner(app.GridLayout);
            app.TemperaturaSpinner.Step = 0.1;
            app.TemperaturaSpinner.ValueDisplayFormat = '%.1f °C';
            app.TemperaturaSpinner.ValueChangedFcn = createCallbackFcn(app, @TemperaturaSpinnerValueChanged, true);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.InterpolacinTab = uitab(app.TabGroup);
            app.InterpolacinTab.Title = 'Interpolación';
            app.GridLayout2 = uigridlayout(app.InterpolacinTab);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Interpolación de Eficiencia del Panel Solar')
            xlabel(app.UIAxes, 'Temperatura (°C)')
            ylabel(app.UIAxes, 'Eficiencia (%)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [15 20 25 30 35 40];
            app.UIAxes.XTickLabel = {'15'; '20'; '25'; '30'; '35'; '40'};
            colormap(app.UIAxes, 'hot')
            app.CheckPolinomica = uicheckbox(app.GridLayout2);
            app.CheckPolinomica.ValueChangedFcn = createCallbackFcn(app, @CheckPolinomicaValueChanged, true);
            app.CheckPolinomica.Text = 'Polinómica';
            app.CheckPolinomica.Value = true;
            app.CheckSpline = uicheckbox(app.GridLayout2);
            app.CheckSpline.ValueChangedFcn = createCallbackFcn(app, @CheckSplineValueChanged, true);
            app.CheckSpline.Text = 'Spline';
            app.CheckSpline.Value = true;
            app.CheckLineal = uicheckbox(app.GridLayout2);
            app.CheckLineal.ValueChangedFcn = createCallbackFcn(app, @CheckLinealValueChanged, true);
            app.CheckLineal.Text = 'Lineal';
            app.CheckLineal.Value = true;
            app.ErrorPorcentualTab = uitab(app.TabGroup);
            app.ErrorPorcentualTab.Title = 'Error Porcentual';
            app.GridLayout2_2 = uigridlayout(app.ErrorPorcentualTab);
            app.UIAxes_2 = uiaxes(app.GridLayout2_2);
            title(app.UIAxes_2, 'Error en Interpolación de Eficiencia del Panel Solar')
            xlabel(app.UIAxes_2, 'Temperatura (°C)')
            ylabel(app.UIAxes_2, 'Eficiencia (%)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTick = [15 20 25 30 35 40];
            app.UIAxes_2.XTickLabel = {'15'; '20'; '25'; '30'; '35'; '40'};
            colormap(app.UIAxes_2, 'hot')
            app.CheckPolinomica_2 = uicheckbox(app.GridLayout2_2);
            app.CheckPolinomica_2.ValueChangedFcn = createCallbackFcn(app, @CheckPolinomica_2ValueChanged, true);
            app.CheckPolinomica_2.Text = 'Polinómica';
            app.CheckPolinomica_2.Value = true;
            app.CheckSpline_2 = uicheckbox(app.GridLayout2_2);
            app.CheckSpline_2.ValueChangedFcn = createCallbackFcn(app, @CheckSpline_2ValueChanged, true);
            app.CheckSpline_2.Text = 'Spline';
            app.CheckSpline_2.Value = true;
            app.CheckLineal_2 = uicheckbox(app.GridLayout2_2);
            app.CheckLineal_2.ValueChangedFcn = createCallbackFcn(app, @CheckLineal_2ValueChanged, true);
            app.CheckLineal_2.Text = 'Lineal';
            app.CheckLineal_2.Value = true;
            app.TemperaturaSlider = uislider(app.GridLayout);
            app.TemperaturaSlider.Limits = [15 40];
            app.TemperaturaSlider.MajorTicks = [15 20 25 30 35 40];
            app.TemperaturaSlider.ValueChangedFcn = createCallbackFcn(app, @TemperaturaSliderValueChanged, true);
            app.TemperaturaSlider.Value = 15;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProyectoFinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end